classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SourceMessagePanel              matlab.ui.container.Panel
        PreviewPanel                    matlab.ui.container.Panel
        PlayButton                      matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        SelectyourfilePanel             matlab.ui.container.Panel
        ContentTypeSwitch               matlab.ui.control.Switch
        ChooseImageButton               matlab.ui.control.Button
        PreferencesPanel                matlab.ui.container.Panel
        FrequencyDeviationEditField     matlab.ui.control.NumericEditField
        FrequencyDeviationEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        SNRSlider                       matlab.ui.control.Slider
        SNRSliderLabel                  matlab.ui.control.Label
        ModulationButtonGroup           matlab.ui.container.ButtonGroup
        PCMButton                       matlab.ui.control.RadioButton
        FMButton                        matlab.ui.control.RadioButton
        SSBButton                       matlab.ui.control.RadioButton
        SendButton                      matlab.ui.control.Button
        TransmissionSideLabel           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PlayButton2                     matlab.ui.control.Button
        Label2                          matlab.ui.control.Label
        RecieverSideLabel               matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %   Global variables
    properties (Access = private)
        contentType = "Image" % by default set to Image
        % Parameters for FM modulation
        Fs % Sampling frequency
        Fc;    % Carrier frequency
        dev;   % Frequency deviation
        SourceData; % Source image or audio data
        AudioSamplFreq; % Sampling freq of selected audio
        selectedMod; %modulation method
        snr;
        t; % Time axis vector
        modulated_signal;
        demodulated_signal;
    end

    methods (Access = private)

        function fm_mod(app)
            % FM modulation
            app.modulated_signal = fmmod(app.SourceData, app.Fc, app.Fs, app.dev);

            % Add White Gaussian noise to simulate channel effects
            noisy_signal = awgn( app.modulated_signal , app.snr, 'measured');
            app.Label2.Visible = true;
            app.Label2.Text = "Please wait receiving signal...";
            pause(2);

            % FM demodulation
            app.demodulated_signal = fmdemod(noisy_signal, app.Fc, app.Fs, app.dev);
            app.Label2.Visible = false;
            app.UIAxes.Visible = true;

            app.plotdemod();

        end

        function PCM(app)
            % PCM encoding
            encodedSignal = app.SourceData(:);  % Convert 2D matrix to 1D vector

            % Repetition coding for error correction
            repetitionFactor = 3;
            encodedSignalRepeated = repelem(encodedSignal, repetitionFactor);

            app.modulated_signal = awgn(encodedSignalRepeated, app.snr, 'measured');

            app.Label2.Visible = true;
            app.Label2.Text = "Please wait receiving signal...";
            pause(1);
            % Repetition decoding for error correction
            decodedSignalRepeated = reshape( app.modulated_signal , repetitionFactor, []);

            % Perform simple repetition decoding
            decodedSignal = mode(decodedSignalRepeated);

            % Reshape the decoded signal to the original image size
            app.demodulated_signal = reshape(decodedSignal, size(app.SourceData));
            app.Label2.Visible = false;
            app.UIAxes.Visible = true;
            app.plotdemod();
        end

        function plotdemod(app)
            plot(app.modulated_signal, "Parent", app.UIAxes3);
            if app.contentType == "Image"
                imshow(app.demodulated_signal, 'Parent', app.UIAxes2);
            else
                plot(app.t,app.demodulated_signal, "Parent", app.UIAxes2);
                app.PlayButton2.Visible = true;
            end
        end

        function SSB(app)
            % SSB Modulation
            app.modulated_signal = ssbmod(app.SourceData, app.Fc, app.Fs);
            noisy_signal = awgn( app.modulated_signal , app.snr, 'measured');
            app.Label2.Visible = true;
            app.Label2.Text = "Please wait receiving signal...";
            pause(2);
            % SSB Demodulation
            app.demodulated_signal = ssbdemod(noisy_signal, app.Fc, app.Fs);
            app.Label2.Visible = false;
            app.UIAxes.Visible = true;
            app.plotdemod();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            % Image preprocessing
            if app.contentType == "Image"
                [filename, filepath] = uigetfile({'*.jpg;*.png;'}, 'Select File to Open');
                fullname = [filepath, filename];
                app.SourceData = imread(fullname);

                if size(app.SourceData, 3) == 3
                    app.SourceData = rgb2gray(app.SourceData);
                end
                %  Normalizing in 0 to 1
                app.SourceData = double(app.SourceData) / 255;
                stem(app.SourceData, 'Parent', app.UIAxes);
                app.Image.ImageSource = fullname;
                % Audio preprocessing
            else

                [filename, filepath] = uigetfile({'*.mp3;*.ogg;'}, 'Select File to Open');
                fullname = [filepath, filename];
                [app.SourceData, app.AudioSamplFreq] = audioread(fullname);
                app.t = (0:length(app.SourceData)-1) / app.AudioSamplFreq;
                plot(app.t,app.SourceData, "Parent", app.UIAxes);
                app.PlayButton.Visible = true;
            end

        end

        % Value changed function: ContentTypeSwitch
        function ContentTypeSwitchValueChanged(app, event)
            
            app.contentType = app.ContentTypeSwitch.Value;
            app.Image.Visible = true;
            if app.contentType == "Audio"
                app.Image.Visible = false;
            end
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)

            if app.selectedMod.Text == "FM"
                app.fm_mod();
            elseif app.selectedMod.Text == "SSB"
                app.SSB();
            else
                app.PCM();
            end

        end

        % Selection changed function: ModulationButtonGroup
        function ModulationButtonGroupSelectionChanged(app, event)
            app.selectedMod = app.ModulationButtonGroup.SelectedObject;

        end

        % Value changed function: CarrierFrequencyEditField
        function CarrierFrequencyEditFieldValueChanged(app, event)
            app.Fc = app.CarrierFrequencyEditField.Value;

        end

        % Value changed function: SamplingFrequencyEditField
        function SamplingFrequencyEditFieldValueChanged(app, event)
            app.Fs = app.SamplingFrequencyEditField.Value;

        end

        % Value changed function: FrequencyDeviationEditField
        function FrequencyDeviationEditFieldValueChanged(app, event)
            app.dev = app.FrequencyDeviationEditField.Value;

        end

        % Value changed function: SNRSlider
        function SNRSliderValueChanged(app, event)
            app.snr = app.SNRSlider.Value;

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            soundview(app.SourceData, app.AudioSamplFreq);
        end

        % Button pushed function: PlayButton2
        function PlayButton2Pushed(app, event)
            soundview(app.demodulated_signal, app.AudioSamplFreq);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {702, 702};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {571, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TransmissionSideLabel = uilabel(app.LeftPanel);
            app.TransmissionSideLabel.Text = 'Transmission Side';
            app.SendButton = uibutton(app.LeftPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.PreferencesPanel = uipanel(app.LeftPanel);
            app.PreferencesPanel.Title = 'Preferences';
            app.ModulationButtonGroup = uibuttongroup(app.PreferencesPanel);
            app.ModulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModulationButtonGroupSelectionChanged, true);
            app.ModulationButtonGroup.Title = 'Modulation ';
            app.SSBButton = uiradiobutton(app.ModulationButtonGroup);
            app.SSBButton.Text = 'SSB';
            app.SSBButton.Value = true;
            app.FMButton = uiradiobutton(app.ModulationButtonGroup);
            app.FMButton.Text = 'FM';
            app.PCMButton = uiradiobutton(app.ModulationButtonGroup);
            app.PCMButton.Text = 'PCM';
            app.SNRSliderLabel = uilabel(app.PreferencesPanel);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.PreferencesPanel);
            app.SNRSlider.ValueChangedFcn = createCallbackFcn(app, @SNRSliderValueChanged, true);
            app.CarrierFrequencyEditFieldLabel = uilabel(app.PreferencesPanel);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.PreferencesPanel, 'numeric');
            app.CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyEditFieldValueChanged, true);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.PreferencesPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.PreferencesPanel, 'numeric');
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditFieldValueChanged, true);
            app.FrequencyDeviationEditFieldLabel = uilabel(app.PreferencesPanel);
            app.FrequencyDeviationEditFieldLabel.Text = 'Frequency Deviation';
            app.FrequencyDeviationEditField = uieditfield(app.PreferencesPanel, 'numeric');
            app.FrequencyDeviationEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyDeviationEditFieldValueChanged, true);
            app.SourceMessagePanel = uipanel(app.LeftPanel);
            app.SourceMessagePanel.Title = 'Source Message';
            app.SelectyourfilePanel = uipanel(app.SourceMessagePanel);
            app.SelectyourfilePanel.Title = 'Select your file';
            app.ChooseImageButton = uibutton(app.SelectyourfilePanel, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image...';
            app.ContentTypeSwitch = uiswitch(app.SelectyourfilePanel, 'slider');
            app.ContentTypeSwitch.Items = {'Image', 'Audio'};
            app.ContentTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @ContentTypeSwitchValueChanged, true);
            app.ContentTypeSwitch.Value = 'Image';
            app.PreviewPanel = uipanel(app.SourceMessagePanel);
            app.PreviewPanel.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewPanel);
            title(app.UIAxes, 'Message signal Representation')
            ylabel(app.UIAxes, {''; ''})
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.PreviewPanel);
            app.PlayButton = uibutton(app.PreviewPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Received Data')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Modulated Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RecieverSideLabel = uilabel(app.RightPanel);
            app.RecieverSideLabel.Text = 'Reciever Side';
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            app.PlayButton2 = uibutton(app.RightPanel, 'push');
            app.PlayButton2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton2Pushed, true);
            app.PlayButton2.Visible = 'off';
            app.PlayButton2.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end